classdef SSP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        Button_9      matlab.ui.control.Button
        BetaButton_3  matlab.ui.control.Button
        Button_8      matlab.ui.control.Button
        BetaButton    matlab.ui.control.Button
        Button_7      matlab.ui.control.Button
        Label_2       matlab.ui.control.Label
        Button_6      matlab.ui.control.Button
        Button_5      matlab.ui.control.Button
        Button_4      matlab.ui.control.Button
        BetaButton_2  matlab.ui.control.Button
        Label         matlab.ui.control.Label
        Button_2      matlab.ui.control.Button
        Button        matlab.ui.control.Button
        UIAxes6       matlab.ui.control.UIAxes
        UIAxes5       matlab.ui.control.UIAxes
        UIAxes4       matlab.ui.control.UIAxes
        UIAxes3       matlab.ui.control.UIAxes
        UIAxes2       matlab.ui.control.UIAxes
        UIAxes        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Property % Description

    end
    
    methods (Access = public)
        
        function [w,en,yn] = LMS(app,xn,dn,mu,M)
            %这是一个LMS滤波器去噪的函数
            %自适应滤波，不断迭代求滤波器系数，直到滤波结果趋近于理想信号（期望输出）
            %自己加噪再去噪？
            %都有理想信号了，还是自己录的音，为什么要先加噪再去噪？炫技看滤波效果？
            %最后输出的应该是近似输入的"理想信号"的东西
            %去除信号处理产生的误差？
            %懂了，手动加噪之后再去噪看看是不是真的能去，不直接用原声是因为那样不好玩（不是）
            %之前预设的mu是0.09，M是150，可供参考
            %以下噪声产生器？
            % noise=sqrt(0.05)*randn(n,1);
            % %产生均值为0，方差为0.1的噪声信号
            % 
            % ar=[1,0.5];
            % noise_ar=filter(1,ar,noise);
            % %产生AR模型的噪声

            %LMS实现程序
            %   输入：
            % xn   输入信号       列向量
            % dn   理想信号       列向量
            % mu   收敛常数       标量
            % itr  迭代次数       标量   （非输入）
            % M    滤波器的阶数   标量
            %   输出：
            % w    滤波器的系数矩阵    大小为M×itr  每一列代表一次迭代后的系数
            % en   误差信号           大小为itr×1  每一行代表一次迭代后产生的误差
            % yn   滤波器的输出信号    列向量
            
            
            itr = length(xn); %使得迭代次数为输入信号xn的长度
            
            w = zeros(M,itr);%将滤波器的初始值设置为0
            en = zeros(itr,1);%将初始误差设置为0
            
            %迭代更新滤波器的参数
            for k = M:itr    %要保证输入延时后的信号有效，所以实际的迭代次数只有（itr-M）次，
                x = xn(k:-1:k-M+1);%将输入信号延迟，使得滤波器的每个抽头都有输入
                y = w(:,k-1).'*x;  %计算出滤波器的输出
                en(k) = dn(k)-y;   %得出误差信号
                w(:,k) = w(:,k-1)+mu*en(k)*x;%迭代更新滤波器的系数
            end
                
            
            %滤波器参数固定后，得出经过优化滤波器滤波后的输出信号
            yn = 0*ones(1,length(xn));
            %inf意为无穷大，将yn的初始值置为无穷大，绘制图像的时候将无法显示
            %我进行了一些修改，把yn初始值置零了，防止后续影响小波变换（小波变换要求不允许有无穷或空值）
            
            for k = M:itr
                x = xn(k:-1:k-M+1);
                yn(k) = w(:,k).'*x; %再滤波
            end
        end
        
        function [output,fs] = muteEliminationBeta(app,input,inputFs)
            %静音消除函数
            %利用双门限法检测端点，并消除静音，返回无静音的语音
            %每帧信号的短时能量、短时过零率可以判断是否是空白部分
            % 输入参数：
            % input: 输入音频信号
            % inputFs: 输入音频采样频率

            %以下两参数本来是函数输入的，现变更为函数中设定
            % energyThreshold: 能量阈值 (一般值范围在0到1之间)
            % minSilenceDuration: 最小静音持续时间 (秒)

            %实现效果：去除掉了开头结尾的静音部分（没人说话的部分）
            %缺陷：中间的静音部分去不掉，而且有时候会很敏感，考虑调节阈值参数

            %把原函数移动到下面去了，这里现在是Beta版，是为了解决中间空白部分消除而产生的，有明显的问题
            %如何修改有待观察
            
            % 读取音频信号
            % 由于现在改为直接输入信号本身而不是文件名，省去一步
            %[inputSignal, fs] = audioread(inputFile);
            inputSignal = input(:, 1); % 如果是立体声，取单声道信号
            fs = inputFs;
        
            % 设定参数，有分帧操作（把信号划分为不同的帧，帧之中包含多个信号离散值，帧之间可重叠）
            frameSize = 256;    % 帧大小，即每一帧的样本数，已知信号是离散的，意味着每一帧时长是frameSize/fs
            hopSize = 128;      % 帧移，即向后移动多少个样本可以得到下一帧，这里有帧内容的重叠
            numFrames = floor((length(inputSignal) - frameSize) / hopSize) + 1; %这里是为了计算一共有多少帧
            energyThreshold = 0.01; %能量阈值
            minSilenceDuration = 0.3; %最小静音时长
            
            % 计算每帧的能量
            energy = zeros(numFrames, 1); %先来一个全零矩阵，长度就是帧数，方便后续操作
            for i = 1:numFrames %注意看，这里遍历每一帧
                frame = inputSignal((i-1)*hopSize + 1:(i-1)*hopSize + frameSize); %这里是每一帧的信号，是数组！
                % 注意看，信号被规定为从这一帧第一个到这一帧最后一个，冒号前是起始，冒号后是终结，不解释
                energy(i) = sum(frame.^2); % 能量计算，一帧中所有信号离散值平方后累加，算能量
            end
            
            % 归一化能量
            energy = energy / max(energy); %把能量尺度变化到0到1之间，注意，energy是数组！
            
            % 双门限检测
            silentFrames = energy < energyThreshold; %看静音的帧：能量小于阈值，注意这个也是数组！
            %注意看，这里的silentFrames其实是布尔量！存的是这个序号的帧是否为空（静音）！
            
            % 寻找语音的起始和结束帧
            startFrame = find(~silentFrames, 1, 'first'); % 第一个非静音帧
            endFrame = find(~silentFrames, 1, 'last');   % 最后一个非静音帧
            
            if isempty(startFrame) || isempty(endFrame)
                % 如果没有语音部分，返回原信号
                disp('未检测到语音段，输出原信号。');
                audiowrite(outputFile, inputSignal, fs);
                return;
            end
            
            % 寻找语音的起始和结束帧
            speechIntervals = findSpeechIntervals(app,silentFrames, minSilenceDuration, numFrames,frameSize,fs);
        
            % 提取有效的语音信号
            outputSignal = extractSpeechSignal(app,inputSignal, speechIntervals, hopSize, frameSize);
        
            % 输出不含静音的语音信号
            %audiowrite(outputFile, outputSignal, fs);
            %disp(['处理完成，输出文件: ', outputFile]);
            output=outputSignal; %输出信号
        
            % 播放输出信号
            sound(outputSignal, fs); %播放信号
            
            
        end
        
        function speechIntervals = findSpeechIntervals(app,silentFrames, minSilenceDuration, numFrames,frameSize,fs)
            % 找到语音片段
            speechIntervals = []; %注意，有两行/列的，2*N大小的，是矩阵！一行/列开始帧序号，一行/列结束帧序号！
            startFrame = []; %这里是保存开始的帧数
            for i = 1:numFrames %按帧遍历整个语音信号
                if ~silentFrames(i) && isempty(startFrame) %这一帧不为空且还没开始
                    startFrame = i; % 开始新的语音片段
                elseif silentFrames(i) && ~isempty(startFrame) %这一帧为空且已经开始了
                    endFrame = i - 1; % 结束当前语音片段
                    speechIntervals = [speechIntervals; startFrame, endFrame]; % 保存语音片段
                    %注意看！这里保存是先保存它自己！然后是开始和结束的帧序号！是为了避免前面保存的信息丢失！
                    startFrame = []; % 重置开始帧
                end
            end
            % 检查是否以语音片段结束
            if ~isempty(startFrame) %前面已经遍历结束了，这里是最后一帧时是否在语音片段中
                speechIntervals = [speechIntervals; startFrame, numFrames]; % 保存最后的语音片段
            end
        
            % 过滤掉小于最小静音持续时间的静音片段
            minSilentFrames = ceil(minSilenceDuration / (frameSize / fs)); %最小语音时长除以一帧时长，即：至少多少帧
            speechIntervals = filterIntervals(app,speechIntervals, minSilentFrames); %调用函数，函数实现就在下面
        end
        
        function filteredIntervals = filterIntervals(app,intervals, minSilentFrames)
            % 过滤掉小于最小静音持续时间的语音片段
            filteredIntervals = []; %和之前的speechIntervals一样
            for i = 1:size(intervals, 1) %操作：遍历之前的语音段数组（保存的不是真的语音段，而是语音段开始结束的帧序号）
                if (intervals(i, 2) - intervals(i, 1) + 1) >= minSilentFrames %一行行(列)看，结束帧序号减去开始帧序号看看小不小于最小帧数？
                    filteredIntervals = [filteredIntervals; intervals(i, :)]; %不小于最小帧数那就好，存入新矩阵，最后返回之
                end
            end
            %至此两层函数返回后得到了一个矩阵，里面存储了语音段首末的帧序号
        end
        
        function outputSignal = extractSpeechSignal(app,inputSignal, speechIntervals, hopSize, frameSize)
            % 提取有效的语音信号
            outputSignal = [];
            for i = 1:size(speechIntervals, 1) %继续遍历语音段数组
                startSample = (speechIntervals(i, 1) - 1) * hopSize + 1; %提取出实际离散信号的对应的位数！
                endSample = min(speechIntervals(i, 2) * hopSize, length(inputSignal));
                outputSignal = [outputSignal; inputSignal(startSample:endSample)]; 
                % 先存自己，再从输入信号中找到对应位数的信号存入，最后得到的是信号本身！
                %所谓分帧那都是虚拟的，都没有对信号做出什么改变，只是方便操作罢了，最后还是要回归信号本身
            end
        end
        
        function [output,fs] = muteElimination(app,input,inputFs)
            %静音消除函数
            %利用双门限法检测端点，并消除静音，返回无静音的语音
            %每帧信号的短时能量、短时过零率可以判断是否是空白部分
            % 输入参数：
            % input: 输入音频信号
            % inputFs：输入信号采样频率
            
            %自定义
            % energyThreshold: 能量阈值 (一般值范围在0到1之间)
            % minSilenceDuration: 最小静音持续时间 (秒)

            % 输出参数
            % output: 输出音频信号

            %实现效果：去除掉了开头结尾的静音部分（没人说话的部分）
            %缺陷：中间的静音部分去不掉，而且有时候会很敏感，考虑调节阈值参数
            
            % 读取音频信号
            % 由于现在改为直接输入信号本身而不是文件名，省去一步
            %[inputSignal, fs] = audioread(inputFile);
            inputSignal = input(:, 1); % 如果是立体声，取单声道信号
            fs = inputFs;
        
            % 设定参数，有分帧操作（把信号划分为不同的帧，帧之中包含多个信号离散值，帧之间可重叠）
            frameSize = 256;    % 帧大小，即每一帧的样本数，已知信号是离散的，意味着每一帧时长是frameSize/fs
            hopSize = 128;      % 帧移，即向后移动多少个样本可以得到下一帧，这里有帧内容的重叠
            numFrames = floor((length(inputSignal) - frameSize) / hopSize) + 1; %这里是为了计算一共有多少帧
            energyThreshold = 0.1; %能量阈值
            minSilenceDuration = 0.3; %最小静音时长
            
            % 计算每帧的能量
            energy = zeros(numFrames, 1); %先来一个全零矩阵，长度就是帧数，方便后续操作
            for i = 1:numFrames %注意看，这里遍历每一帧
                frame = inputSignal((i-1)*hopSize + 1:(i-1)*hopSize + frameSize); %这里是每一帧的信号，是数组！
                % 注意看，信号被规定为从这一帧第一个到这一帧最后一个，冒号前是起始，冒号后是终结，不解释
                energy(i) = sum(frame.^2); % 能量计算，一帧中所有信号离散值平方后累加，算能量
            end
            
            % 归一化能量
            energy = energy / max(energy); %把能量尺度变化到0到1之间，注意，energy是数组！
            
            % 双门限检测
            silentFrames = energy < energyThreshold; %看静音的帧：能量小于阈值，注意这个也是数组！
            
            % 寻找语音的起始和结束帧
            startFrame = find(~silentFrames, 1, 'first'); % 第一个非静音帧
            endFrame = find(~silentFrames, 1, 'last');   % 最后一个非静音帧
            
            if isempty(startFrame) || isempty(endFrame)
                % 如果没有语音部分，返回原信号
                disp('未检测到语音段，输出原信号。');
                audiowrite(outputFile, inputSignal, fs);
                return;
            end
            
            % 判断是否存在连续静音
            % 计算最小静音持续时间的帧数
            minSilentFrames = ceil(minSilenceDuration * fs / hopSize);
            
            % 检查在语音信号的开始前是否有静音
            if startFrame > minSilentFrames && all(silentFrames(1:startFrame-1))
                startFrame = startFrame - minSilentFrames; % 考虑静音段
            end
            
            % 检查在语音信号的结束后是否有静音
            if endFrame + minSilentFrames <= numFrames && all(silentFrames(endFrame+1:endFrame+minSilentFrames))
                endFrame = endFrame + minSilentFrames; % 考虑静音段
            end
        
            % 提取有效的语音信号
            startSample = (startFrame - 1) * hopSize + 1;
            endSample = min((endFrame - 1) * hopSize + frameSize, length(inputSignal));
            speechSignal = inputSignal(startSample:endSample);
        
            % 输出不含静音的语音信号
            %audiowrite(outputFile, speechSignal, fs);
            output = speechSignal;
            
            % 播放输出信号
            sound(speechSignal, fs);
            %disp(['处理完成，输出文件: ', outputFile]);
        end
        
        function [w,y,e] = RLS(app,x,d,lambda,M)
            %函数预留：RLS最小二乘法滤波算法
            % RLS Recursive Least Squares Algorithm
            % RLS滤波算法
            % Inputs:
            % 输入：
            % x     - Input signal (noisy signal) 输入信号 (含噪信号)
            % d     - Desired signal (original signal) 期望信号 (原始信号)
            % lambda - Forgetting factor (0 < lambda < 1) 遗忘因子
            % M     - Filter order (number of coefficients) 滤波器阶数(系数)
        
            if nargin < 3
                lambda = 0.99; % Default forgetting factor 默认遗忘因子
            end
            if nargin < 4
                M = 32; % Default filter order 默认滤波器阶数
            end
            
            n = length(x); % Length of input signal 输入信号长度
            w = zeros(M, 1); % Initialize filter coefficients 初始化滤波器系数
            P = eye(M) * 1000; % Initialize error covariance matrix 初始化误差协方差矩阵
            y = zeros(n, 1); % Output signal 输出信号
            e = zeros(n, 1); % Error signal 误差信号
        
            for k = M:n
                x_k = flipud(x(k-M+1:k)); % Current input signal frame (length M) 信号分帧
                y(k) = w' * x_k; % Calculate filter output 计算滤波器输出
                e(k) = d(k) - y(k); % Calculate error 计算误差
                
                k_k = (P * x_k) / (lambda + x_k' * P * x_k); % Calculate gain vector 计算增益向量
                w = w + k_k * e(k); % Update filter coefficients 更新滤波器系数
                
                P = (P - k_k * (x_k' * P)) / lambda; % Update error covariance matrix 更新误差协方差矩阵
            end
        end
    end
    
    methods (Access = private)
        
        function [e,fs]=AEC(app) %AEC算法尝试
            % 读取音频信号（原始信号和带回声的信号）
            [inputSignal, fs] = audioread('T.wav'); % 原始信号
            [echoedSignal, fs] = audioread('Tcharge.wav'); % 带回声的信号
            
            % AEC参数设置
            mu = 0.1; % 步长
            filterOrder = 32; % 滤波器阶数
            numSamples = length(echoedSignal); % 采样点数
            
            % 初始化自适应滤波器
            W = zeros(filterOrder, 1); % 滤波器权重初始化
            y = zeros(numSamples, 1); % 输出信号初始化
            e = zeros(numSamples, 1); % 误差信号初始化
            
            % AEC算法进行回声消除
            for n = filterOrder:numSamples
                % 获取当前输入信号的样本
                x = echoedSignal(n:-1:n-filterOrder+1); % 获取当前样本及前面 filterOrder-1 个样本
                
                % 计算输出信号
                y(n) = W' * x; 
                
                % 计算误差信号
                e(n) = inputSignal(n) - y(n); 
                
                % 更新滤波器权重，防止除以零
                normFactor = x' * x; % 输入信号的能量
                if normFactor ~= 0
                    W = W + mu * (e(n) / normFactor) * x; 
                end
            end
            
            % 播放信号
            % sound(inputSignal, fs); % 播放原始信号
            % pause(length(inputSignal)/fs + 1); % 暂停
            % sound(echoedSignal, fs); % 播放带回声的信号
            % pause(length(echoedSignal)/fs + 1); % 暂停
            e=e*2;
            % sound(e, fs); % 播放去除回声后的信号
            % 
            % 
            % plot(app.UIAxes5,e);
            % title(app.UIAxes5,"回声消除：时域特性");
            % 
            % fdau=abs(fft(e));
            % plot(app.UIAxes6,fdau);
            % title(app.UIAxes6,"回声消除：频域特性");
            % 
            % 保存去除回声后的信号
            audiowrite('Tcharge.wav', e, fs);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %开始录音函数
            fs=44100;
            R=audiorecorder(fs,16,1); %录音准备——定义录音音频对象
            record(R); %开始录音
            set(app.Button,'userdata',R);
            set(app.Label,'text',"正在录音");
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            %结束录音函数
            R=get(app.Button,'userdata');
            stop(R); %结束录音
            set(app.Label,'text',"录音完成");
            audio=getaudiodata(R); %获得录音数组
            %巴特沃斯滤波器：频域1/(1+(D/D0)^(2n))，平滑低通(?)
            %butter(n,Wn,'ftype')：构建巴特沃斯滤波器系数，然后再代入filter才是滤波器
            %n:滤波器阶数（对应前面的n）；Wn:截止频率系数，0~1，1是奈奎斯特频率，Wn表示截止频率和采样频率的比值
            %Wn=Fc/(Fs/2);Fc:(期望)截止频率，Fs:采样频率
            %ftype:滤波类型，有低通low，高通high，带通bandpass，带阻bandstop
            %[B,A]=butter(3, 1000/4000); %butter滤波器——返回归一化截止频率为1/4的3阶低通数字巴特沃斯滤波器的传递函数系数
            %rec=filter(B,A,audio); %一维数字滤波器——使用由分子和分母系数B和A定义的有理传递函数对输入数据audio进行滤波
            %据了解，filter是递归滤波器，会构建一个y(n)=b(1->N+1)x(n->n-N)/(a1+a(2->M)y(n-1->n-M)，不保真
            %a为极点（分母），b为零点（分子）
            %建议是搭配其他滤波器系数构建函数使用
            audiowrite("T.wav",audio,44100); %保存音频文件
            audiowrite("Tcharge.wav",audio,44100); %保存音频文件
            %rec1=resample(rec,80,441); %以新的固定速率重新采样均匀或非均匀数据
            %audioread函数：返回参数1：y0:数组，表示语音信号序列；参数2：fs:采样频率，最佳是44100
            [y0,fs]=audioread("T.wav");
            N=length(y0); %取长度
            plot(app.UIAxes,y0); %画图
            title(app.UIAxes,"原语音信号：时域特性");
            %fft为快速傅里叶变换，由DFT、DFS演化而来，课上有说
            %输入参数：参数1：x:向量或矩阵被变换的对象；参数2：N:计算傅里叶变换的长度，可不写。
            %输出参数：参数1：y:复数数组，变换结果。用abs取绝对值（模长）可得频谱数组（幅频特性）
            fy0=abs(fft(y0,N));
            plot(app.UIAxes3,fy0);
            %plot函数绘制曲线图，较为自由，输入参数可以是x,y(横纵坐标)，也可以只有y(纵坐标)
            %这里plot第一输入参数是显示的组件名
            title(app.UIAxes3,"原语音信号：频域特性");

            sound(audio,fs); %播放语音

            %至此我们完成了最基本的录音操作，后续计划完成语音信号的增强（去噪锐化）、回声产生与消除、端点检测等操作

            %计划与猜想：要求：增强、回声、端点检测
            %可能添加：变声（shiftPitch？）、混叠（硬加？）、语者识别（基于MFCC梅尔倒谱函数？），等。目前均未实装
            %增强：低通滤波，维纳法、谱减法、小波变换？不止去噪
            %回声：延时+衰减？for循环硬算？sin频谱（类似的）
            %端点：遍历信号设置阈值然后剪掉？
            %你到底学没学过信号处理啊？
            %是否引入深度学习？
        end

        % Button pushed function: BetaButton_2
        function BetaButton_2Pushed(app, event)
            %语音增强函数Beta
            %是真的有处理，不是拿原声来糊弄大家
            %LMS自适应滤波（主力）+小波分析阈值去噪（辅助？）
            %缺点：LMS需要原信号支撑，不然不知道滤什么；小波不需要原信号，但是噪声大了效果很抽象（？）
            %回声消除效果不好评价，可去回声，但原声会模糊

            [aunoi,fs]=audioread("Tcharge.wav"); %读取含噪声信号（如果有，否则还是原信号）
            [au,~]=audioread("T.wav"); %读取原信号，采样频率应该都一样（？）
            
            [e,fs]=AEC(app); %AEC自适应滤波，语音去回声（？）

            %LMS自适应滤波先来一波兜个底（真神器也），输入有噪声用噪声，没有噪声用原声（相当于不变）
            M=150;
            mu=0.09;
            [W,en,audio]=LMS(app,e,au,mu,M); %LMS自适应滤波，前面有定义，迭代法
            audio=audio*2; %LMS超水平发挥力挽狂澜挽救全损音质（虽然还是有亿点点模糊）

            audiowrite("Tcharge.wav",audio,fs);
            % [e,fs]=AEC(app);
            % 
            % 
            % [W,en,audio]=LMS(app,e,au,mu,M); %LMS自适应滤波，前面有定义，迭代法
            % audio=audio*2;

            %本来想先LMS的，但是做了这个之后小波变换会报错说应为有限值，考虑LMS中信号有部分被置于无穷
            %但是现在我把LMS函数修改了，改成先全域置零，这样就不会有无穷了（？）


            % 基于小波分析的语音增强（？）
            % 一开始只有小波阈值去噪，效果感觉实在有点离谱，就加了个LMS自适应滤波兜底
            % 主要是LMS滤波要用到原声，相当于开挂了（？），感觉像是对着答案凑过程
            % 实际效果：本来没噪音的听不出来有什么区别，频谱有肉眼不可见的幅值下降
            % 本来有噪音的（自加随机噪声）所有信号一起削弱，噪音变小了语音也模糊了（全损音质，AR噪声直接废了），考虑参数设置不当
            % 但是自加噪音较小时，可以基本滤除噪声，语音略有模糊但问题不大
            % 但是上面那个LMS滤波效果还是很好的，必要时可以考虑，但只能先加噪再去噪，最后得到比原版模糊一点
            % 暂未设置预处理（分帧加窗等）
            % 小波变换：对原信号进行分解，分频段对语音进行分析
            % level为分的段数，第一个为低频系数，其余为高频系数
            % 由数字图像频率特性考虑，语音信号低频部分应为信息主体，高频部分包含了原信号的细节和噪声部分
            % 信号增强大致分为平滑（低通滤波）和锐化（高通滤波）
            % 平滑滤除噪声但增加了模糊程度（高频被滤除）；锐化增强细节但放大了噪声（低频被滤除）
            % 在此使用的小波分析大体上属于平滑增强，应该还要做锐化的


            %level=20;
            level=int32(log2(length(audio)));
            [C,L]=wavedec(audio,level,'db8'); %小波变换。注意：先LMS可能提示X应为有限，考虑前面LMS的问题导致信号有INF或NULL
            approx_coeffs=C(1:L(1)); %最小那个是低频系数，不用变
            detail_coeffs=cell(level,1); %剩下的都是高频系数，可以变，保存进新的数组里
            for i=1:level
                detail_coeffs{i}=C(L(i)+1:L(i+1)); %保存
            end
            
            sigma=median(abs(C))/0.6745; %噪声估计，这里sigma是噪声的标准差，取整个细节系数的中位数除以0.6745
            %sigma=wnoisest(C,L); %有个估算噪声的函数
            %alpha=3; 
            %测试下来alpha越大频谱被砍掉的就越多（？）
            alpha=sqrt(2*log(length(C))); %传说中alpha参数应该这样计算
            %alpha=2;
            %threshold=wbmpen(C,L,sigma,alpha); %有个计算阈值函数
            threshold=alpha*sigma; %alpha取到3，阈值设定为alpha乘上sigma
            for i=1:level %只改高频信号，因为噪声和细节主要在高频里
                detail_coeffs{i}=wthresh(detail_coeffs{i},'s',threshold); 
                %软阈值，小波系数小于阈值直接归零，大于则由小波系数绝对值减去阈值整体再乘以原信号
            end
            C_D=C; %主要获取第一个系数即低频系数，因为低频不变
            for i=1:level
                C_D(L(i)+1:L(i+1))=detail_coeffs{i}; %阈值操作之后的高频系数导入
            end
            en_audio=waverec(C_D,L,'db8'); %反变换重构

            % keepapp=1;
            % en_audio=wdencmp("gbl",C,L,'db8',threshold,'s',keepapp);

            %加一个高通巴特沃斯滤波器锐化一下，但是感觉声音更模糊了
            % [b,a]=butter(2,0.1,"high");
            % en_audio=filter(b,a,en_audio);

            % %LMS自适应滤波来一波兜个底
            % M=150;
            % mu=0.09;
            % [W,en,audio]=LMS(en_audio,au,mu,M); %LMS自适应滤波，前面有定义，迭代法
            % en_audio=audio*2; %LMS超水平发挥力挽狂澜挽救全损音质（虽然还是有亿点点模糊）


            plot(app.UIAxes5,en_audio);
            title(app.UIAxes5,"语音增强：时域特性");

            en_faudio=abs(fft(en_audio));
            plot(app.UIAxes6,en_faudio);
            title(app.UIAxes6,"语音增强：频域特性");

            sound(en_audio,fs);

            audiowrite("Tcharge.wav",en_audio,fs); %保存音频文件

            % 先这样吧，以上是小波变换语音增强，使用效果存疑


        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            %播放函数
            [y0,fs]=audioread("T.wav"); %播放原信号
            N=length(y0); %取长度
            plot(app.UIAxes,y0); %画图
            title(app.UIAxes,"原语音信号：时域特性");
            %fft为快速傅里叶变换，由DFT、DFS演化而来，课上有说
            %输入参数：参数1：x:向量或矩阵被变换的对象；参数2：N:计算傅里叶变换的长度，可不写。
            %输出参数：参数1：y:复数数组，变换结果。用abs取绝对值（模长）可得频谱数组（幅频特性）
            fy0=abs(fft(y0,N));
            plot(app.UIAxes3,fy0);
            %plot函数绘制曲线图，较为自由，输入参数可以是x,y(横纵坐标)，也可以只有y(纵坐标)
            %这里plot第一输入参数是显示的组件名
            title(app.UIAxes3,"原语音信号：频域特性");
            sound(y0,fs);
            audiowrite("Tcharge.wav",y0,fs); %保存音频文件
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            %加噪函数
            [au,fs]=audioread("Tcharge.wav"); %读改变后的信号
            n=length(au);
            noise=sqrt(0.05)*randn(n,1);
            %产生均值为0，方差为0.1的噪声信号
            
            ar=[1,0.5];
            noise_ar=filter(1,ar,noise);
            %产生AR模型的噪声
            aunoise=au+noise_ar;
            audiowrite("Tcharge.wav",aunoise,fs); %保存音频文件
            plot(app.UIAxes2,aunoise);
            title(app.UIAxes2,"语音加噪：时域特性");
            faunoise=abs(fft(aunoise,n));
            plot(app.UIAxes4,faunoise);
            title(app.UIAxes4,"语音加噪：频域特性");
            sound(aunoise,fs);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            %回声产生函数
            %思路：延时、衰减、叠加（迫真回声（？），甚至没有用到传统信号处理（？））
            %其实我们用了滤波器
            [audio,fs]=audioread("Tcharge.wav");
            delaySec=0.3; %参数：延时秒数
            cutOff=0.4; %参数：衰减比例
            delaySam=round(fs*delaySec); %延时多少离散点？
            a=1; %滤波器参数设置
            b=[1 zeros(1,delaySam) cutOff];
            outPut=filter(b,a,audio); %滤波
            outPut=filter(b,a,outPut); %滤波
            outPut=outPut(1:length(audio));
            audiowrite("Tcharge.wav",outPut,fs);
            plot(app.UIAxes2,outPut);
            title(app.UIAxes2,"回声产生：时域特性");
            fout=abs(fft(outPut));
            plot(app.UIAxes4,fout);
            title(app.UIAxes4,"回声产生：频域特性");
            sound(outPut,fs);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            %端点检测函数
            %检测首末段，中间忽略不计
            % en=0.01; %参数设置：能量阈值
            % min=0.2; %参数设置：最小语音持续时间
            [in,fs]=audioread("Tcharge.wav"); %参数设置：导入文件名
            %out="Tcharge.wav"; %参数设置：导出文件名
            [audio,fs]=muteElimination(app,in,fs); %调用函数：静音消除
            %[audio.fs]=audioread("Tcharge.wav"); %读取数据，画个图
            plot(app.UIAxes2,audio);
            title(app.UIAxes2,"端点检测和静音消除：时域特性");
            faudio=abs(fft(audio));
            plot(app.UIAxes4,faudio);
            title(app.UIAxes2,"端点检测和静音消除：频域特性");
            audiowrite("Tcharge.wav",audio,fs);
        end

        % Button pushed function: BetaButton
        function BetaButtonPushed(app, event)
            % 端点检测Beta版
            % 所有空白语音一律清除
            % 实际操作下来有明显的问题，具体表现为一顿乱删，不是空白的语音也可能被删，听起来会很怪
            % 由于检测认为算法合理，考虑参数设置不当
            [in,fs]=audioread("Tcharge.wav"); %参数设置：导入文件名
            %out="Tcharge.wav"; %参数设置：导出文件名
            [audio,fs]=muteEliminationBeta(app,in,fs); %调用函数：静音消除Beta版
            %[audio.fs]=audioread("Tcharge.wav"); %读取数据，画个图
            plot(app.UIAxes2,audio);
            title(app.UIAxes2,"端点检测和静音消除：时域特性");
            faudio=abs(fft(audio));
            plot(app.UIAxes4,faudio);
            title(app.UIAxes2,"端点检测和静音消除：频域特性");
            audiowrite("Tcharge.wav",audio,fs); %也保存音频
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            %语音增强
            %是真的有处理，不是拿原声来糊弄大家
            %LMS自适应滤波（主力）+小波分析阈值去噪（辅助？）
            %缺点：LMS需要原信号支撑，不然不知道滤什么；小波不需要原信号，但是噪声大了效果很抽象（？）
            %先有前面的Beta再有的这个，区别在于把小波变换和LMS滤波换了个位置，前面那个小波分析老是报错，令人害怕（？）
            %然而现在前面的Beta又加上了AEC自适应函数，可以去除回声，这个回声去除效果不好

            [audio,fs]=audioread("Tcharge.wav"); %读取含噪声信号（如果有，否则还是原信号）
            [au,~]=audioread("T.wav"); %读取原信号，采样频率应该都一样（？）
            
            %小波变换起手式
            %level=20;
            level=int32(log2(length(audio)));
            [C,L]=wavedec(audio,level,'db8'); %小波变换。注意：先LMS可能提示X应为有限
            approx_coeffs=C(1:L(1)); %最小那个是低频系数，不用变
            detail_coeffs=cell(level,1); %剩下的都是高频系数，可以变，保存进新的数组里
            for i=1:level
                detail_coeffs{i}=C(L(i)+1:L(i+1)); %保存
            end
            
            sigma=median(abs(C))/0.6745; %噪声估计，这里sigma是噪声的标准差，取整个细节系数的中位数除以0.6745
            %sigma=wnoisest(C,L); %有个估算噪声的函数
            %alpha=3; 
            %测试下来alpha越大频谱被砍掉的就越多（？）
            alpha=sqrt(2*log(length(C))); %传说中alpha参数应该这样计算
            %alpha=2;
            %threshold=wbmpen(C,L,sigma,alpha); %有个计算阈值函数
            threshold=alpha*sigma; %alpha取到3，阈值设定为alpha乘上sigma
            for i=1:level %只改高频信号，因为噪声和细节主要在高频里
                detail_coeffs{i}=wthresh(detail_coeffs{i},'s',threshold); 
                %软阈值，小波系数小于阈值直接归零，大于则由小波系数绝对值减去阈值整体再乘以原信号
            end
            C_D=C; %主要获取第一个系数即低频系数，因为低频不变
            for i=1:level
                C_D(L(i)+1:L(i+1))=detail_coeffs{i}; %阈值操作之后的高频系数导入
            end
            en_audio=waverec(C_D,L,'db8'); %反变换重构

            % keepapp=1;
            % en_audio=wdencmp("gbl",C,L,'db8',threshold,'s',keepapp);

            %加一个高通巴特沃斯滤波器锐化一下，但是感觉声音更模糊了
            % [b,a]=butter(2,0.1,"high");
            % en_audio=filter(b,a,en_audio);

            %LMS自适应滤波来一波兜个底
            M=150;
            mu=0.09;
            [W,en,audio]=LMS(app,en_audio,au,mu,M); %LMS自适应滤波，前面有定义，迭代法
            en_audio=audio*2; %LMS超水平发挥力挽狂澜挽救全损音质（虽然还是有亿点点模糊）


            plot(app.UIAxes5,en_audio);
            title(app.UIAxes5,"语音增强：时域特性");

            en_faudio=abs(fft(en_audio));
            plot(app.UIAxes6,en_faudio);
            title(app.UIAxes6,"语音增强：频域特性");

            sound(en_audio,fs);

            audiowrite("Tcharge.wav",en_audio,fs); %保存音频文件
        end

        % Button pushed function: BetaButton_3
        function BetaButton_3Pushed(app, event)
            %语音回声消除Beta版，效果差评
            [e,fs]=AEC(app); %全包服务
            mu=0.09;
            M=150;
            % [audiox,fs]=audioread("T.wav");
            % [W,en,audio]=LMS(app,e,audiox,mu,M);
            % %[W2,en2,audio]=LMS(app,audio,audiox,mu,M);
            % 
            % 
            % audiowrite("Tcharge.wav",audio,fs);
            [e,~]=audioread("Tcharge.wav");
            % 
            % [e,fs]=AEC(app);
            % [W,en,audio]=LMS(app,e,audiox,mu,M);
            audio=e;
            sound(audio, fs); % 播放去除回声后的信号


            plot(app.UIAxes5,audio);
            title(app.UIAxes5,"回声消除：时域特性");

            fdau=abs(fft(audio));
            plot(app.UIAxes6,fdau);
            title(app.UIAxes6,"回声消除：频域特性");
            audiowrite("Tcharge.wav",audio,fs);
        end

        % Callback function
        function LMSButtonPushed(app, event)
            %已删除
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            %播放当前音频
            [audio,fs]=audioread("Tcharge.wav");
            sound(audio, fs);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Title')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Title')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Title')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Title')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '开始录音';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '结束录音';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '语音信号处理';
            app.BetaButton_2 = uibutton(app.UIFigure, 'push');
            app.BetaButton_2.ButtonPushedFcn = createCallbackFcn(app, @BetaButton_2Pushed, true);
            app.BetaButton_2.Text = '语音增强Beta';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '重置原声';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '语音加噪';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '语音回声产生';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '语音信号处理';
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '语音端点检测';
            app.BetaButton = uibutton(app.UIFigure, 'push');
            app.BetaButton.ButtonPushedFcn = createCallbackFcn(app, @BetaButtonPushed, true);
            app.BetaButton.Text = '语音端点检测Beta';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '语音增强';
            app.BetaButton_3 = uibutton(app.UIFigure, 'push');
            app.BetaButton_3.ButtonPushedFcn = createCallbackFcn(app, @BetaButton_3Pushed, true);
            app.BetaButton_3.Text = '语音回声消除Beta';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '播放当前音频';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SSP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end